classdef etc_render_fsbrain_tms_nav < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TMSNavFigure               matlab.ui.Figure
        EfieldPanel                matlab.ui.container.Panel
        CalcButton                 matlab.ui.control.Button
        TextArea                   matlab.ui.control.TextArea
        ImportButton               matlab.ui.control.Button
        ExportButton               matlab.ui.control.Button
        TunePanel                  matlab.ui.container.Panel
        offsetSlider               matlab.ui.control.Slider
        offsetSliderLabel          matlab.ui.control.Label
        horizSlider                matlab.ui.control.Slider
        horizSliderLabel           matlab.ui.control.Label
        rotateSlider               matlab.ui.control.Slider
        rotateSliderLabel          matlab.ui.control.Label
        vertSlider                 matlab.ui.control.Slider
        vertSliderLabel            matlab.ui.control.Label
        TMStargetPanel             matlab.ui.container.Panel
        VecUpCheckBox              matlab.ui.control.CheckBox
        VecNormCheckBox            matlab.ui.control.CheckBox
        StrcoilLamp                matlab.ui.control.Lamp
        CoilLamp                   matlab.ui.control.Lamp
        LoadstrcoilButton          matlab.ui.control.Button
        xButton                    matlab.ui.control.Button
        LoadcoilButton             matlab.ui.control.Button
        vertexindexEditField       matlab.ui.control.EditField
        vertexindexEditFieldLabel  matlab.ui.control.Label
        XYZEditField               matlab.ui.control.EditField
        XYZEditFieldLabel          matlab.ui.control.Label
        CRSEditField               matlab.ui.control.EditField
        CRSEditFieldLabel          matlab.ui.control.Label
        MNIEditField               matlab.ui.control.EditField
        MNIEditField_2Label        matlab.ui.control.Label
        coilmodelDropDown          matlab.ui.control.DropDown
        coilmodelDropDownLabel     matlab.ui.control.Label
        GoButton                   matlab.ui.control.Button
        ScalpPanel                 matlab.ui.container.Panel
        SButton                    matlab.ui.control.Button
        SurfaceDropDown            matlab.ui.control.DropDown
        surfaceDropDownLabel       matlab.ui.control.Label
        ShowscalpCheckBox          matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
    end
    
    properties (Access = public)
        flag_show_up=0;
        flag_show_norm=0;
        up_obj=[]; % Description
        norm_obj=[]; % Description

        coil_center_no_tune=[]; %TMS coil center before any tuning
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global etc_render_fsbrain;

            app.SurfaceDropDown.Items={};
            if(isfield(etc_render_fsbrain,'surf_obj'))
                for idx=1:length(etc_render_fsbrain.surf_obj)
                    [pp,ff]=fileparts(etc_render_fsbrain.surf_obj(idx).filename);
                    app.SurfaceDropDown.Items{idx}=ff;
                    app.SurfaceDropDown.Value=ff;
                end;
                if(isempty(app.SurfaceDropDown.Items))
                    app.ShowscalpCheckBox.Enable='off';
                else
                    app.ShowscalpCheckBox.Enable='on';
                end;
            else
                app.ShowscalpCheckBox.Enable='off';
            end;

            if(isfield(etc_render_fsbrain,'surf_obj'))
                for idx=1:length(etc_render_fsbrain.surf_obj)
                    if(etc_render_fsbrain.surf_obj(idx).patch_handle.Visible)
                        app.ShowscalpCheckBox.Value=1;
                    else
                        app.ShowscalpCheckBox.Value=0;
                    end;
                end;
            end;

            if(isfield(etc_render_fsbrain,'object'))
                if(~isempty(etc_render_fsbrain.object)) %coil patch exists
                    app.CoilLamp.Color='g';
                end;
            end;
        end

        % Callback function
        function loadscalpButtonPushed(app, event)
            global etc_render_fsbrain;

            [filename, pathname, filterindex] = uigetfile({'*.*','surface'});
            if(filename==0) return; end;


            [vv,ff]=read_surf(sprintf('%s/%s',pathname, filename));

            %geometrical information about scalp
            TR = triangulation(ff+1,vv);
            surf_center= incenter(TR);
            surf_norm = faceNormal(TR);


            [eS, TriPS, TriMS] = mt(TR);
            if(~isfield(etc_render_fsbrain,'surf_obj')) etc_render_fsbrain.surf_obj=[]; end;
            etc_render_fsbrain.surf_obj(end+1).vertex=vv;
            etc_render_fsbrain.surf_obj(end).face=ff;
            etc_render_fsbrain.surf_obj(end).TR=TR;
            etc_render_fsbrain.surf_obj(end).surf_center=surf_center;
            etc_render_fsbrain.surf_obj(end).surf_norm=surf_norm;
            etc_render_fsbrain.surf_obj(end).eS=eS;
            etc_render_fsbrain.surf_obj(end).TriPS=TriPS;
            etc_render_fsbrain.surf_obj(end).TriMS=TriMS;
            etc_render_fsbrain.surf_obj(end).color=app.ScalpColorButton.BackgroundColor;
            etc_render_fsbrain.surf_obj(end).flag_show=1;
            etc_render_fsbrain.surf_obj(end).filename=sprintf('%s/%s',pathname, filename);

            app.ShowscalpCheckBox.Value=1;

            etc_render_fsbrain.surf_obj(end).patch_handle=patch('vertices',vv,'faces',ff+1,'edgecolor','none','facecolor',etc_render_fsbrain.surf_obj(end).color,'facealpha',0.2); %show scalp
            
            axis vis3d tight;

            etc_render_fsbrain_handle('draw_pointer');

        end

        % Callback function
        function ScalpColorButtonPushed(app, event)
            c = uisetcolor;
            if(length(c)>1)
                app.ScalpColorButton.BackgroundColor=c;

                global etc_render_fsbrain;
                etc_render_fsbrain.surf_obj(end).color=c;
                if(isfield(etc_render_fsbrain.surf_obj(end),'patch_handle'))
                    etc_render_fsbrain.surf_obj(end).patch_handle.FaceColor=c;
                end;
                etc_render_fsbrain_handle('draw_pointer');
            end;
        end

        % Value changed function: ShowscalpCheckBox
        function ShowscalpCheckBoxValueChanged(app, event)
            value = app.ShowscalpCheckBox.Value;

            global etc_render_fsbrain;

            surface_list={};
            for surf_idx=1:length(etc_render_fsbrain.surf_obj)
                [pp,ff]=fileparts(etc_render_fsbrain.surf_obj(surf_idx).filename);
                surface_list{end+1}=ff;
            end;
            [a,b]=ismember(app.SurfaceDropDown.Value,surface_list); %determine which surface is selected

            if(a<eps) return; end; %no matched surface!

            if(value)
                if(isfield(etc_render_fsbrain.surf_obj(end),'patch_handle'))
                    set(etc_render_fsbrain.surf_obj(b).patch_handle,'visible','on');
                end;
            else
                if(isfield(etc_render_fsbrain.surf_obj(end),'patch_handle'))
                    set(etc_render_fsbrain.surf_obj(b).patch_handle,'visible','off');
                end;                
            end;
        end

        % Close request function: TMSNavFigure
        function TMSNavFigureCloseRequest(app, event)
            try
                delete(app.norm_obj);
            catch
            end;
            try
                delete(app.up_obj);
            catch
            end;
            try
                delete(app.scalp_obj);
            catch
            end;
            try
                delete(app)
            catch
            end;
        end

        % Value changed function: MNIEditField
        function MNIEditFieldValueChanged(app, event)
            value = app.MNIEditField.Value;
            
            global etc_render_fsbrain;

            if(~isempty(etc_render_fsbrain.click_vertex))
                try
                    coord= str2num(value);
                    mni=[coord 1]';
                    click_vertex_vox=inv(etc_render_fsbrain.vol.vox2ras)*inv(etc_render_fsbrain.vol_pre_xfm)*inv(etc_render_fsbrain.talxfm)*mni;
                    %click_vertex_vox=inv(etc_render_fsbrain.vol.tkrvox2ras)*inv(etc_render_fsbrain.vol_pre_xfm)*inv(etc_render_fsbrain.talxfm)*mni;
                    click_vertex_vox=click_vertex_vox(1:3)';

                    surface_coord=etc_render_fsbrain.vol.tkrvox2ras*[click_vertex_vox(:); 1];
                    surface_coord=surface_coord(1:3);

                    vv=etc_render_fsbrain.orig_vertex_coords;
                    dist=sqrt(sum((vv-repmat([surface_coord(1),surface_coord(2),surface_coord(3)],[size(vv,1),1])).^2,2));
                    [min_dist,min_dist_idx]=min(dist);
                    %surface_coord=etc_render_fsbrain.vertex_coords(min_dist_idx,:)';

                    etc_render_fsbrain_handle('draw_pointer','surface_coord',surface_coord,'min_dist_idx',min_dist_idx,'click_vertex_vox',click_vertex_vox);
                catch ME
                end;
            end;


        end

        % Value changed function: CRSEditField
        function CRSEditFieldValueChanged(app, event)
            value = app.CRSEditField.Value;
            
            global etc_render_fsbrain;

            if(~isempty(etc_render_fsbrain.click_vertex))
                try
                    coord= str2num(value);
                    click_vertex_vox=[coord];
                    tmp=[click_vertex_vox 1]';
                    mni=etc_render_fsbrain.talxfm*etc_render_fsbrain.vol_pre_xfm*etc_render_fsbrain.vol.vox2ras*tmp;
                    %mni=etc_render_fsbrain.talxfm*etc_render_fsbrain.vol_pre_xfm*etc_render_fsbrain.vol.tkrvox2ras*tmp;
                    mni=min(1:3)';

                    surface_coord=etc_render_fsbrain.vol.tkrvox2ras*[click_vertex_vox(:); 1];
                    surface_coord=surface_coord(1:3);

                    vv=etc_render_fsbrain.orig_vertex_coords;
                    dist=sqrt(sum((vv-repmat([surface_coord(1),surface_coord(2),surface_coord(3)],[size(vv,1),1])).^2,2));
                    [min_dist,min_dist_idx]=min(dist);
                    %surface_coord=etc_render_fsbrain.vertex_coords(min_dist_idx,:)';

                    etc_render_fsbrain_handle('draw_pointer','surface_coord',surface_coord,'min_dist_idx',min_dist_idx,'click_vertex_vox',click_vertex_vox);
                catch ME
                end;
            end;

        end

        % Value changed function: XYZEditField
        function XYZEditFieldValueChanged(app, event)
            value = app.XYZEditField.Value;

            global etc_render_fsbrain;

            if(~isempty(etc_render_fsbrain.click_vertex))
                try
                    coord= str2num(value);

                    surface_coord=[coord];
                    tmp=[surface_coord 1]';
                    click_vertex_vox=inv(etc_render_fsbrain.vol.tkrvox2ras)*tmp;
                    click_vertex_vox=click_vertex_vox(1:3)';

                    vv=etc_render_fsbrain.orig_vertex_coords;
                    dist=sqrt(sum((vv-repmat([surface_coord(1),surface_coord(2),surface_coord(3)],[size(vv,1),1])).^2,2));
                    [min_dist,min_dist_idx]=min(dist);
                    %surface_coord=etc_render_fsbrain.vertex_coords(min_dist_idx,:)';

                    etc_render_fsbrain_handle('draw_pointer','surface_coord',surface_coord,'min_dist_idx',min_dist_idx,'click_vertex_vox',click_vertex_vox);
                catch ME
                end;
            end;

        end

        % Value changed function: vertexindexEditField
        function vertexindexEditFieldValueChanged(app, event)
            value = app.vertexindexEditField.Value;
                       
            global etc_render_fsbrain;

            if(~isempty(etc_render_fsbrain.click_vertex))
                try
                    coord_idx= str2num(value);
                    vv=etc_render_fsbrain.vertex_coords;
                    coord=vv(coord_idx,:);

                    surface_coord=[coord];
                    tmp=[surface_coord 1]';
                    click_vertex_vox=inv(etc_render_fsbrain.vol.tkrvox2ras)*tmp;
                    click_vertex_vox=click_vertex_vox(1:3)';

                    vv=etc_render_fsbrain.orig_vertex_coords;
                    dist=sqrt(sum((vv-repmat([surface_coord(1),surface_coord(2),surface_coord(3)],[size(vv,1),1])).^2,2));
                    [min_dist,min_dist_idx]=min(dist);
                    %surface_coord=etc_render_fsbrain.vertex_coords(min_dist_idx,:)';

                    etc_render_fsbrain_handle('draw_pointer','surface_coord',surface_coord,'min_dist_idx',min_dist_idx,'click_vertex_vox',click_vertex_vox);
                catch ME
                end;
            end;

            
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % TMS coil placement
            global etc_render_fsbrain;

            if(isempty(app.vertexindexEditField.Value)) return; end;

            %define the target in the brain
            click_idx= str2num(app.vertexindexEditField.Value); %<------define target indexx
            target=etc_render_fsbrain.vertex_coords(click_idx,:);
            

            surface_list={};
            for surf_idx=1:length(etc_render_fsbrain.surf_obj)
                [pp,ff]=fileparts(etc_render_fsbrain.surf_obj(surf_idx).filename);
                surface_list{end+1}=ff;
            end;
            [a,scalp_idx]=ismember(app.SurfaceDropDown.Value,surface_list); %determine which surface is selected

            if(a<eps) return; end; %no matched surface!

            surf_center=etc_render_fsbrain.surf_obj(scalp_idx).surf_center;
            surf_norm=etc_render_fsbrain.surf_obj(scalp_idx).surf_norm;

            %calculate target-coil distance
            dist=sqrt(sum(bsxfun(@minus,surf_center,target).^2,2));
            [min_dist,min_idx]=min(dist);

            %show coil position with the minimal distance
            %cc=get(gca,'colororder');
            %hq=quiver3(surf_center(min_idx,1),surf_center(min_idx,2),surf_center(min_idx,3),surf_norm(min_idx,1),surf_norm(min_idx,2),surf_norm(min_idx,3),15,'color','r');

            coil_center=surf_center(min_idx,:); % in mm
            coil_orientation=target-coil_center;
            fprintf('distance between the target and coil center = %2.2f (mm)\n',norm(coil_orientation));

            coil_orientation=coil_orientation./norm(coil_orientation);
            scalp_orientation=surf_norm(min_idx,:);

            % derive the rotation matrix between two vectors (from, to)
            % https://math.stackexchange.com/questions/180418/calculate-rotation-matrix-to-align-vector-a-to-vector-b-in-3d
            ssc = @(v) [0 -v(3) v(2); v(3) 0 -v(1); -v(2) v(1) 0];
            RU = @(A,B) eye(3) + ssc(cross(A,B)) + ssc(cross(A,B))^2*(1-dot(A,B))/(norm(cross(A,B))^2);

            fprintf('before moving: coil origin: %s \n',mat2str(etc_render_fsbrain.object.UserData.Origin(:).',3));
            fprintf('before moving: coil origin target: %s \n',mat2str(coil_center(:).',3));
            
            %etc_render_fsbrain.object.UserData.Axis(:).'

            coil_shift=-etc_render_fsbrain.object.UserData.Origin(:)+coil_center(:);
            n=norm(etc_render_fsbrain.object.UserData.Axis(:)'-coil_orientation);
            if(n>eps)
                RR=RU(etc_render_fsbrain.object.UserData.Axis(:)',coil_orientation(:)');
            else
                RR=eye(3);
            end;
            if(find(isnan(RR(:)))) keyboard; end;
            if(find(isnan(coil_shift(:)))) keyboard; end;

            % update object_xfm matrix
            R=eye(4);
            R(1:3,4)=etc_render_fsbrain.object.UserData.Origin(:);
            R_tx_now_mm=R;
            R_tx_now=R_tx_now_mm;
            R_tx_now(1:3,4)=R_tx_now(1:3,4)./1e3;
            

            R=eye(4);
            R(1:3,1:3)=RR;
            R_rot=R';

            R=eye(4);
            R(1:3,4)=coil_shift(:)./1e3; %in m
            R_trans=R;

            R=eye(4);
            R(1:3,4)=coil_shift(:); %in mm
            R_trans_mm=R;

            etc_render_fsbrain.object_xfm=R_trans*R_tx_now*inv(R_rot)*inv(R_tx_now);


            %transform object in visualiation
            vv=etc_render_fsbrain.object.Vertices;
            vv(:,4)=1;
            vv=(R_trans_mm*R_tx_now_mm*inv(R_rot)*inv(R_tx_now_mm)*vv')';
            etc_render_fsbrain.object.Vertices=vv(:,1:3);


            if(~isempty(app.norm_obj))
                tmp=[app.norm_obj.XData; app.norm_obj.YData; app.norm_obj.ZData; ones(size(app.norm_obj.XData))];
                tmp=(R_trans_mm*R_tx_now_mm*inv(R_rot)*inv(R_tx_now_mm)*tmp);
                %tmp=((inv(R_c)*inv(R_rotz)*R_c)*R_trans_mm*R_tx_now*inv(R_rot)*inv(R_tx_now)*tmp);
                app.norm_obj.XData=tmp(1,:);
                app.norm_obj.YData=tmp(2,:);
                app.norm_obj.ZData=tmp(3,:);
            end;
            
            if(~isempty(app.up_obj))
                tmp=[app.up_obj.XData; app.up_obj.YData; app.up_obj.ZData; ones(size(app.up_obj.XData))];
                tmp=(R_trans_mm*R_tx_now_mm*inv(R_rot)*inv(R_tx_now_mm)*tmp);
                %tmp=((inv(R_c)*inv(R_rotz)*R_c)*R_trans_mm*R_tx_now*inv(R_rot)*inv(R_tx_now)*tmp);
                app.up_obj.XData=tmp(1,:);
                app.up_obj.YData=tmp(2,:);
                app.up_obj.ZData=tmp(3,:);
            end;


            %transform data
            tmp=(R_trans_mm*R_tx_now_mm*inv(R_rot)*inv(R_tx_now_mm)*[etc_render_fsbrain.object.UserData.Origin(:)' 1]')';
            %tmp=((inv(R_c)*inv(R_rotz)*(R_c))*R_trans_mm*R_tx_now*inv(R_rot)*inv(R_tx_now)*[etc_render_fsbrain.object.UserData.Origin(:)' 1]')';
            etc_render_fsbrain.object.UserData.Origin=tmp(1:3);
            fprintf('after moving: coil origin: %s \n',mat2str(etc_render_fsbrain.object.UserData.Origin(:).',3));

            tmp=(inv(R_rot)*[etc_render_fsbrain.object.UserData.Axis(:)' 1]')';
            %tmp=((inv(R_c)*inv(R_rotz)*(R_c))*R_trans_mm*R_tx_now*inv(R_rot)*inv(R_tx_now)*[etc_render_fsbrain.object.UserData.Axis(:)' 1]')';
            etc_render_fsbrain.object.UserData.Axis=tmp(1:3);

            tmp=(inv(R_rot)*[etc_render_fsbrain.object.UserData.Up(:)' 1]')';
            %tmp=((inv(R_c)*inv(R_rotz)*(R_c))*R_trans_mm*R_tx_now*inv(R_rot)*inv(R_tx_now)*[etc_render_fsbrain.object.UserData.Up(:)' 1]')';
            etc_render_fsbrain.object.UserData.Up=tmp(1:3);


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %aligning UP toward +z

            coil_center=etc_render_fsbrain.object.UserData.Origin(:);
            R_c_mm=eye(4);
            R_c_mm(1:3,4)=-coil_center(1:3);
            R_c=R_c_mm;
            R_c(1:3,4)=R_c(1:3,4)./1e3;

            coil_axis=etc_render_fsbrain.object.UserData.Axis(:).';
            tmp=coil_axis./norm(coil_axis);
            x=tmp(1);
            y=tmp(2);
            z=tmp(3);

            X1=etc_render_fsbrain.object.UserData.Origin(1);
            Y1=etc_render_fsbrain.object.UserData.Origin(2);
            Z1=etc_render_fsbrain.object.UserData.Origin(3);

            X2=0;
            Y2=0;
            Z2=100;
            vtop=[X2-X1; Y2-Y1; Z2-Z1]';
            vtop_perp=vtop-sum(vtop.*tmp).*tmp;
           
            vup=etc_render_fsbrain.object.UserData.Up;


            %determine the rotation between two vectors;
            xx = cross(vtop_perp,vup);
            c = sign(dot(xx,etc_render_fsbrain.object.UserData.Axis)) * norm(xx);
            deg = atan2d(c,dot(vtop_perp,vup)).*pi./180;

            %[azimuth,elevation,r] = cart2sph(x,y,z);
            %deg=azimuth;
            theta=(deg);
            r_rotz=[cos(theta)+x*x*(1-cos(theta)), x*y*(1-cos(theta))-z*sin(theta), x*z*(1-cos(theta))+y*sin(theta);
                y*x*(1-cos(theta))+z*sin(theta) cos(theta)+y*y*(1-cos(theta)) y*z*(1-cos(theta))-x*sin(theta);
                z*x*(1-cos(theta))-y*sin(theta) z*y*(1-cos(theta))+x*sin(theta) cos(theta)+z*z*(1-cos(theta))];
            %https://en.wikipedia.org/wiki/Rotation_matrix
            R_rotz=eye(4);
            R_rotz(1:3,1:3)=r_rotz;


            etc_render_fsbrain.object_xfm=inv(R_c)*inv(R_rotz)*(R_c)*etc_render_fsbrain.object_xfm;

            %transform object in visualiation

            vv=etc_render_fsbrain.object.Vertices;
            vv(:,4)=1;
            vv=(inv(R_c_mm)*inv(R_rotz)*(R_c_mm)*vv')';
            etc_render_fsbrain.object.Vertices=vv(:,1:3);


            if(~isempty(app.norm_obj))
                tmp=[app.norm_obj.XData; app.norm_obj.YData; app.norm_obj.ZData; ones(size(app.norm_obj.XData))];
                tmp=((inv(R_c_mm)*inv(R_rotz)*R_c_mm)*tmp);
                %tmp=((inv(R_c)*inv(R_rotz)*R_c)*R_trans_mm*R_tx_now*inv(R_rot)*inv(R_tx_now)*tmp);
                app.norm_obj.XData=tmp(1,:);
                app.norm_obj.YData=tmp(2,:);
                app.norm_obj.ZData=tmp(3,:);
            end;
            
            if(~isempty(app.up_obj))
                tmp=[app.up_obj.XData; app.up_obj.YData; app.up_obj.ZData; ones(size(app.up_obj.XData))];
                tmp=((inv(R_c_mm)*inv(R_rotz)*R_c_mm)*tmp);
                %tmp=((inv(R_c)*inv(R_rotz)*R_c)*R_trans_mm*R_tx_now*inv(R_rot)*inv(R_tx_now)*tmp);
                app.up_obj.XData=tmp(1,:);
                app.up_obj.YData=tmp(2,:);
                app.up_obj.ZData=tmp(3,:);
            end;


            %transform data
            tmp=((inv(R_c_mm)*inv(R_rotz)*R_c_mm)*[etc_render_fsbrain.object.UserData.Origin(:)' 1]')';
            %tmp=((inv(R_c)*inv(R_rotz)*(R_c))*R_trans_mm*R_tx_now*inv(R_rot)*inv(R_tx_now)*[etc_render_fsbrain.object.UserData.Origin(:)' 1]')';
            etc_render_fsbrain.object.UserData.Origin=tmp(1:3);

            tmp=(inv(R_rotz)*[etc_render_fsbrain.object.UserData.Axis(:)' 1]')';
            %tmp=((inv(R_c)*inv(R_rotz)*(R_c))*R_trans_mm*R_tx_now*inv(R_rot)*inv(R_tx_now)*[etc_render_fsbrain.object.UserData.Axis(:)' 1]')';
            etc_render_fsbrain.object.UserData.Axis=tmp(1:3);

            tmp=(inv(R_rotz)*[etc_render_fsbrain.object.UserData.Up(:)' 1]')';
            %tmp=((inv(R_c)*inv(R_rotz)*(R_c))*R_trans_mm*R_tx_now*inv(R_rot)*inv(R_tx_now)*[etc_render_fsbrain.object.UserData.Up(:)' 1]')';
            etc_render_fsbrain.object.UserData.Up=tmp(1:3);

            
%             %final check
%             vup=etc_render_fsbrain.object.UserData.Up
% 
%             %determine the rotation between two vectors;
%             xx = cross(vtop_perp,vup);
%             c = sign(dot(xx,etc_render_fsbrain.object.UserData.Axis)) * norm(xx);
%             deg_after = atan2d(c,dot(vtop_perp,vup)).*pi./180


            %end of aligning UP toward +z
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            app.rotateSlider.Value=0;
            app.offsetSlider.Value=0;
            app.horizSlider.Value=0;
            app.vertSlider.Value=0;
            
            app.coil_center_no_tune=etc_render_fsbrain.object.UserData.Origin;

        end

        % Button pushed function: LoadcoilButton
        function LoadcoilButtonPushed(app, event)
            global etc_render_fsbrain;

            [filename, pathname, filterindex] = uigetfile({'*.mat','TMS coil mat'});
            if(filename==0) return; end;

            Coil = load(sprintf('%s%s',pathname,filename));

            try
                if(isfield(Coil,'P')&isfield(Coil,'t')) app.CoilLamp.Color='g'; else return; end;

                Coil.P=Coil.P.*1e3; %in mm
                figure(etc_render_fsbrain.fig_brain);
                coil_patch=patch('vertices',Coil.P,'faces',Coil.t,'edgecolor','none');
                coil_patch.UserData.Origin=[0 0 0]'; %origin of the coil at +z = 0 mm
                coil_patch.UserData.Axis=[0 0 -1]'; %directional vector; coil plane normal
                coil_patch.UserData.Up=[0 1 0]'; %directional vector; coil upward

                if(isfield(etc_render_fsbrain,'object'))
                    if(~isempty(etc_render_fsbrain.object))
                        delete(etc_render_fsbrain.object);
                        etc_render_fsbrain.object_xfm=[];
                    end;
                end;

                etc_render_fsbrain.object=coil_patch;

                if(~isempty(coil_patch))
                    etc_render_fsbrain.object_Vertices_orig=etc_render_fsbrain.object.Vertices;
                    %if(~isempty(object_xfm))
                    %    etc_render_fsbrain.object_xfm=object_xfm;
                    %else
                        etc_render_fsbrain.object_xfm=eye(4);
                    %end;

                    if(isfield(etc_render_fsbrain.object.UserData,'Origin'))
                        etc_render_fsbrain.object.UserData.Origin_orig=etc_render_fsbrain.object.UserData.Origin;
                    end;
                    if(isfield(etc_render_fsbrain.object.UserData,'Axis'))
                        etc_render_fsbrain.object.UserData.Axis_orig=etc_render_fsbrain.object.UserData.Axis;
                    end;
                    if(isfield(etc_render_fsbrain.object.UserData,'Up'))
                        etc_render_fsbrain.object.UserData.Up_orig=etc_render_fsbrain.object.UserData.Up;
                    end;


                    if(isempty(app.up_obj))
                    else
                        delete(app.up_obj);
                    end;
                    X1=etc_render_fsbrain.object.UserData.Origin(1);
                    Y1=etc_render_fsbrain.object.UserData.Origin(2);
                    Z1=etc_render_fsbrain.object.UserData.Origin(3);

                    X2=etc_render_fsbrain.object.UserData.Origin(1)+etc_render_fsbrain.object.UserData.Up(1).*100; %100 mm line
                    Y2=etc_render_fsbrain.object.UserData.Origin(2)+etc_render_fsbrain.object.UserData.Up(2).*100; %100 mm line
                    Z2=etc_render_fsbrain.object.UserData.Origin(3)+etc_render_fsbrain.object.UserData.Up(3).*100; %100 mm line
                    object_line=[X1 X2; Y1 Y2; Z1 Z2]';

                    app.up_obj=plot3(object_line(:, 1), object_line(:, 2), object_line(:, 3), '-g', 'lineWidth', 2);

                    flag_show_up=1;
                    app.VecUpCheckBox.Value=1;

                    if(isempty(app.norm_obj))
                    else
                        delete(app.norm_obj);
                    end;
                    X1=etc_render_fsbrain.object.UserData.Origin(1);
                    Y1=etc_render_fsbrain.object.UserData.Origin(2);
                    Z1=etc_render_fsbrain.object.UserData.Origin(3);

                    X2=etc_render_fsbrain.object.UserData.Origin(1)+etc_render_fsbrain.object.UserData.Axis(1).*100; %100 mm line
                    Y2=etc_render_fsbrain.object.UserData.Origin(2)+etc_render_fsbrain.object.UserData.Axis(2).*100; %100 mm line
                    Z2=etc_render_fsbrain.object.UserData.Origin(3)+etc_render_fsbrain.object.UserData.Axis(3).*100; %100 mm line
                    object_line=[X1 X2; Y1 Y2; Z1 Z2]';

                    app.norm_obj=plot3(object_line(:, 1), object_line(:, 2), object_line(:, 3), '-m', 'lineWidth', 5);

                    flag_show_norm=1;
                    app.VecNormCheckBox.Value=1;
                    
                else
                    etc_render_fsbrain.object_xfm=[];
                    if(isfield(etc_render_fsbrain.object.UserData,'Origin'))
                        etc_render_fsbrain.object.UserData.Origin_orig=etc_render_fsbrain.object.UserData.Origin;
                    end;
                    if(isfield(etc_render_fsbrain.object.UserData,'Axis'))
                        etc_render_fsbrain.object.UserData.Axis_orig=etc_render_fsbrain.object.UserData.Axis;
                    end;
                    if(isfield(etc_render_fsbrain.object.UserData,'Up'))
                        etc_render_fsbrain.object.UserData.Up_orig=etc_render_fsbrain.object.UserData.Up;
                    end;
                end;


                app.rotateSlider.Value=0;
                app.offsetSlider.Value=0;
                app.horizSlider.Value=0;
                app.vertSlider.Value=0;
                app.coil_center_no_tune=etc_render_fsbrain.object.UserData.Origin;

                [a,b]=ismember(app.coilmodelDropDown.Items,'Coil');
                if(isempty(find(b)))
                    app.coilmodelDropDown.Items{end+1}='Coil';
                end;

                app.coilmodelDropDown.Value='Coil';

            catch
                fprintf('Error in loading coil MAT file [%s]!\n',sprintf('%s%s',pathname,filename));
            end;

        end

        % Button pushed function: SButton
        function SButtonPushed(app, event)
            etc_render_fsbrain_handle('kb','cc','S');
        end

        % Button pushed function: xButton
        function xButtonPushed(app, event)
            etc_render_fsbrain_handle('kb','cc','x');
        end

        % Button pushed function: LoadstrcoilButton
        function LoadstrcoilButtonPushed(app, event)
            global etc_render_fsbrain;

            [filename, pathname, filterindex] = uigetfile({'*.mat','TMS coil mat'});
            if(filename==0) return; end;

            Coil = load(sprintf('%s%s',pathname,filename));

            try
                if(isfield(Coil,'strcoil'))
                    app.StrcoilLamp.Color='g';

                    tmp=Coil.strcoil.Pwire.*1e3;
                    tmp(:,end+1)=1;
                    tmp=tmp';
                    xfm=etc_render_fsbrain.object_xfm;
                    xfm(1:3,4)=xfm(1:3,4).*1e3; %into mm
                    tmp=xfm*tmp;

                    oo=patch('faces', Coil.strcoil.Ewire, 'vertices', tmp(1:3,:)', 'EdgeColor', 'g', 'FaceColor','b');
                    
                    app.rotateSlider.Value=0;
                    app.offsetSlider.Value=0;
                    app.horizSlider.Value=0;
                    app.vertSlider.Value=0;
                    app.coil_center_no_tune=[];


                    [a,b]=ismember(app.coilmodelDropDown.Items,'strcoil');
                    if(isempty(find(b)))
                        app.coilmodelDropDown.Items{end+1}='strcoil';
                    end;

                    app.coilmodelDropDown.Value='strcoil';

                else
                    return;
                end;
            catch
                fprintf('Error in loading strcoil MAT file [%s]!\n',sprintf('%s%s',pathname,filename));
            end;
        end

        % Value changed function: VecNormCheckBox
        function VecNormCheckBoxValueChanged(app, event)
            value = app.VecNormCheckBox.Value;
            
            global etc_render_fsbrain;

            app.flag_show_norm=value;
            if(value)
                if(isempty(app.norm_obj))
                    X1=etc_render_fsbrain.object.UserData.Origin(1);
                    Y1=etc_render_fsbrain.object.UserData.Origin(2);
                    Z1=etc_render_fsbrain.object.UserData.Origin(3);

                    X2=etc_render_fsbrain.object.UserData.Origin(1)+etc_render_fsbrain.object.UserData.Axis(1).*100; %100 mm line
                    Y2=etc_render_fsbrain.object.UserData.Origin(2)+etc_render_fsbrain.object.UserData.Axis(2).*100; %100 mm line
                    Z2=etc_render_fsbrain.object.UserData.Origin(3)+etc_render_fsbrain.object.UserData.Axis(3).*100; %100 mm line
                    object_line=[X1 X2; Y1 Y2; Z1 Z2]';

                    app.norm_obj=plot3(object_line(:, 1), object_line(:, 2), object_line(:, 3), '-m', 'lineWidth', 5);
                else
                    set(app.norm_obj,'visible','on');
                end;
            else
                if(~isempty(app.norm_obj))
                    set(app.norm_obj,'visible','off');
                end;
            end;
        end

        % Value changed function: VecUpCheckBox
        function VecUpCheckBoxValueChanged(app, event)
            value = app.VecUpCheckBox.Value;
            
            global etc_render_fsbrain;

            app.flag_show_up=value;
            if(value)
                if(isempty(app.up_obj))
                    X1=etc_render_fsbrain.object.UserData.Origin(1);
                    Y1=etc_render_fsbrain.object.UserData.Origin(2);
                    Z1=etc_render_fsbrain.object.UserData.Origin(3);

                    X2=etc_render_fsbrain.object.UserData.Origin(1)+etc_render_fsbrain.object.UserData.Up(1).*100; %100 mm line
                    Y2=etc_render_fsbrain.object.UserData.Origin(2)+etc_render_fsbrain.object.UserData.Up(2).*100; %100 mm line
                    Z2=etc_render_fsbrain.object.UserData.Origin(3)+etc_render_fsbrain.object.UserData.Up(3).*100; %100 mm line
                    object_line=[X1 X2; Y1 Y2; Z1 Z2]';

                    app.up_obj=plot3(object_line(:, 1), object_line(:, 2), object_line(:, 3), '-g', 'lineWidth', 2);
                else
                    set(app.up_obj,'visible','on');
                end;
            else
                if(~isempty(app.up_obj))
                    set(app.up_obj,'visible','off');
                end;
            end;

        end

        % Value changed function: rotateSlider
        function rotateSliderValueChanged(app, event)
            value = app.rotateSlider.Value;


            global etc_render_fsbrain;

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %aligning UP toward +z

            coil_center=etc_render_fsbrain.object.UserData.Origin(:);
            R_c_mm=eye(4);
            R_c=eye(4);
            R_c_mm(1:3,4)=-coil_center(1:3);
            R_c(1:3,4)=-coil_center(1:3)./1e3;
            
            coil_axis=etc_render_fsbrain.object.UserData.Axis(:).';
            tmp=coil_axis./norm(coil_axis);
            x=tmp(1);
            y=tmp(2);
            z=tmp(3);

            X1=etc_render_fsbrain.object.UserData.Origin(1);
            Y1=etc_render_fsbrain.object.UserData.Origin(2);
            Z1=etc_render_fsbrain.object.UserData.Origin(3);

            X2=0;
            Y2=0;
            Z2=100;
            vtop=[X2-X1; Y2-Y1; Z2-Z1]';
            vtop_perp=vtop-sum(vtop.*tmp).*tmp;
           
            vup=etc_render_fsbrain.object.UserData.Up;


            %determine the rotation between two vectors;
            xx = cross(vtop_perp,vup);
            c = sign(dot(xx,etc_render_fsbrain.object.UserData.Axis)) * norm(xx);
            deg = atan2d(c,dot(vtop_perp,vup)).*pi./180;
            theta=(deg-value./180*pi);

            r_rotz=[cos(theta)+x*x*(1-cos(theta)), x*y*(1-cos(theta))-z*sin(theta), x*z*(1-cos(theta))+y*sin(theta);
                y*x*(1-cos(theta))+z*sin(theta) cos(theta)+y*y*(1-cos(theta)) y*z*(1-cos(theta))-x*sin(theta);
                z*x*(1-cos(theta))-y*sin(theta) z*y*(1-cos(theta))+x*sin(theta) cos(theta)+z*z*(1-cos(theta))];
            %https://en.wikipedia.org/wiki/Rotation_matrix
            R_rotz=eye(4);
            R_rotz(1:3,1:3)=r_rotz;


            etc_render_fsbrain.object_xfm=inv(R_c)*inv(R_rotz)*(R_c)*etc_render_fsbrain.object_xfm;

            %transform object in visualiation

            vv=etc_render_fsbrain.object.Vertices;
            vv(:,4)=1;
            vv=(inv(R_c_mm)*inv(R_rotz)*(R_c_mm)*vv')';
            etc_render_fsbrain.object.Vertices=vv(:,1:3);


            if(~isempty(app.norm_obj))
                tmp=[app.norm_obj.XData; app.norm_obj.YData; app.norm_obj.ZData; ones(size(app.norm_obj.XData))];
                tmp=((inv(R_c_mm)*inv(R_rotz)*R_c_mm)*tmp);
                %tmp=((inv(R_c)*inv(R_rotz)*R_c)*R_trans_mm*R_tx_now*inv(R_rot)*inv(R_tx_now)*tmp);
                app.norm_obj.XData=tmp(1,:);
                app.norm_obj.YData=tmp(2,:);
                app.norm_obj.ZData=tmp(3,:);
            end;
            
            if(~isempty(app.up_obj))
                tmp=[app.up_obj.XData; app.up_obj.YData; app.up_obj.ZData; ones(size(app.up_obj.XData))];
                tmp=((inv(R_c_mm)*inv(R_rotz)*R_c_mm)*tmp);
                %tmp=((inv(R_c)*inv(R_rotz)*R_c)*R_trans_mm*R_tx_now*inv(R_rot)*inv(R_tx_now)*tmp);
                app.up_obj.XData=tmp(1,:);
                app.up_obj.YData=tmp(2,:);
                app.up_obj.ZData=tmp(3,:);
            end;


            %transform data
            tmp=((inv(R_c_mm)*inv(R_rotz)*R_c_mm)*[etc_render_fsbrain.object.UserData.Origin(:)' 1]')';
            %tmp=((inv(R_c)*inv(R_rotz)*(R_c))*R_trans_mm*R_tx_now*inv(R_rot)*inv(R_tx_now)*[etc_render_fsbrain.object.UserData.Origin(:)' 1]')';
            etc_render_fsbrain.object.UserData.Origin=tmp(1:3);

            tmp=(inv(R_rotz)*[etc_render_fsbrain.object.UserData.Axis(:)' 1]')';
            %tmp=((inv(R_c)*inv(R_rotz)*(R_c))*R_trans_mm*R_tx_now*inv(R_rot)*inv(R_tx_now)*[etc_render_fsbrain.object.UserData.Axis(:)' 1]')';
            etc_render_fsbrain.object.UserData.Axis=tmp(1:3);

            tmp=(inv(R_rotz)*[etc_render_fsbrain.object.UserData.Up(:)' 1]')';
            %tmp=((inv(R_c)*inv(R_rotz)*(R_c))*R_trans_mm*R_tx_now*inv(R_rot)*inv(R_tx_now)*[etc_render_fsbrain.object.UserData.Up(:)' 1]')';
            etc_render_fsbrain.object.UserData.Up=tmp(1:3);


            %end of aligning UP toward +z
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


        end

        % Value changed function: vertSlider
        function vertSliderValueChanged(app, event)
            value = app.vertSlider.Value;
            
            global etc_render_fsbrain;


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %define the target in the brain
            click_idx= str2num(app.vertexindexEditField.Value); %<------define target indexx
            target=etc_render_fsbrain.vertex_coords(click_idx,:);
            

            R_c=eye(4);
            R_c_mm=eye(4);
            R_c(1:3,4)=-target(1:3)./1e3;
            R_c_mm(1:3,4)=-target(1:3);

            %coil_axis=etc_render_fsbrain.object.UserData.Axis(:).';
            coil_axis=cross(etc_render_fsbrain.object.UserData.Axis(:),etc_render_fsbrain.object.UserData.Up(:));
            tmp=coil_axis./norm(coil_axis);
            x=tmp(1);
            y=tmp(2);
            z=tmp(3);

            
            if(isempty(app.coil_center_no_tune)) return; end; %taget not first defined by default

            v1=app.coil_center_no_tune(:)-target(:);
            v2=etc_render_fsbrain.object.UserData.Origin(:)-target(:);

            deg = atan2(norm(cross(v2,v1)), dot(v2,v1));
            if(dot(coil_axis,cross(v1,v2))>0) deg=-deg; end;
            %fprintf('coil now is %1.0f (deg) away from untuned orientation.\n',deg*180/pi);

            %determine the rotation between two vectors;
            theta=value./180*pi-deg;
            %theta=value.*pi./180-deg;
            %fprintf('additional %1.0f (deg) rotation from untuned orientation is requested.\n',theta*180/pi);

            r_rotz=[cos(theta)+x*x*(1-cos(theta)), x*y*(1-cos(theta))-z*sin(theta), x*z*(1-cos(theta))+y*sin(theta);
                y*x*(1-cos(theta))+z*sin(theta) cos(theta)+y*y*(1-cos(theta)) y*z*(1-cos(theta))-x*sin(theta);
                z*x*(1-cos(theta))-y*sin(theta) z*y*(1-cos(theta))+x*sin(theta) cos(theta)+z*z*(1-cos(theta))];
            %https://en.wikipedia.org/wiki/Rotation_matrix
            R_rotz=eye(4);
            R_rotz(1:3,1:3)=r_rotz;


            etc_render_fsbrain.object_xfm=inv(R_c)*inv(R_rotz)*(R_c)*etc_render_fsbrain.object_xfm;

            %transform object in visualiation

            vv=etc_render_fsbrain.object.Vertices;
            vv(:,4)=1;
            vv=(inv(R_c_mm)*inv(R_rotz)*(R_c_mm)*vv')';
            etc_render_fsbrain.object.Vertices=vv(:,1:3);


            if(~isempty(app.norm_obj))
                tmp=[app.norm_obj.XData; app.norm_obj.YData; app.norm_obj.ZData; ones(size(app.norm_obj.XData))];
                tmp=((inv(R_c_mm)*inv(R_rotz)*R_c_mm)*tmp);
                %tmp=((inv(R_c)*inv(R_rotz)*R_c)*R_trans_mm*R_tx_now*inv(R_rot)*inv(R_tx_now)*tmp);
                app.norm_obj.XData=tmp(1,:);
                app.norm_obj.YData=tmp(2,:);
                app.norm_obj.ZData=tmp(3,:);
            end;
            
            if(~isempty(app.up_obj))
                tmp=[app.up_obj.XData; app.up_obj.YData; app.up_obj.ZData; ones(size(app.up_obj.XData))];
                tmp=((inv(R_c_mm)*inv(R_rotz)*R_c_mm)*tmp);
                %tmp=((inv(R_c)*inv(R_rotz)*R_c)*R_trans_mm*R_tx_now*inv(R_rot)*inv(R_tx_now)*tmp);
                app.up_obj.XData=tmp(1,:);
                app.up_obj.YData=tmp(2,:);
                app.up_obj.ZData=tmp(3,:);
            end;


            %transform data
            tmp=((inv(R_c_mm)*inv(R_rotz)*R_c_mm)*[etc_render_fsbrain.object.UserData.Origin(:)' 1]')';
            etc_render_fsbrain.object.UserData.Origin=tmp(1:3);

            tmp=(inv(R_rotz)*[etc_render_fsbrain.object.UserData.Axis(:)' 1]')';
            etc_render_fsbrain.object.UserData.Axis=tmp(1:3);

            tmp=(inv(R_rotz)*[etc_render_fsbrain.object.UserData.Up(:)' 1]')';
            etc_render_fsbrain.object.UserData.Up=tmp(1:3);


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            v1=app.coil_center_no_tune(:)-target(:);
            v2=etc_render_fsbrain.object.UserData.Origin(:)-target(:);
            deg = atan2(norm(cross(v2,v1)), dot(v2,v1));
            if(dot(coil_axis,cross(v1,v2))>0) deg=-deg; end;
            %fprintf('Finally, coil now is %1.0f (deg) away from untuned orientation.\n',deg*180/pi);


        end

        % Value changed function: offsetSlider
        function offsetSliderValueChanged(app, event)
            value = app.offsetSlider.Value;
            

            global etc_render_fsbrain;

            if(isempty(app.coil_center_no_tune)) return; end; %taget not first defined by default


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %define the target in the brain
            target=app.coil_center_no_tune;         


            R_c=eye(4);
            R_c_mm=eye(4);
            R_c(1:3,4)=-target(1:3)./1e3;
            R_c_mm(1:3,4)=-target(1:3);

            coil_axis=etc_render_fsbrain.object.UserData.Axis(:).';
            tmp=coil_axis./norm(coil_axis);
            

            v2=norm(etc_render_fsbrain.object.UserData.Origin(:)-target(:));
            tmp=(value-v2).*tmp;

            R_offset=eye(4);
            R_offset_mm=eye(4);
            R_offset(1:3,4)=-tmp(:)./1e3;
            R_offset_mm(1:3,4)=-tmp(:);


            etc_render_fsbrain.object_xfm=R_offset*etc_render_fsbrain.object_xfm;

            %transform object in visualiation

            vv=etc_render_fsbrain.object.Vertices;
            vv(:,4)=1;
            vv=(R_offset_mm*vv')';
            etc_render_fsbrain.object.Vertices=vv(:,1:3);


            if(~isempty(app.norm_obj))
                tmp=[app.norm_obj.XData; app.norm_obj.YData; app.norm_obj.ZData; ones(size(app.norm_obj.XData))];
                tmp=(R_offset_mm*tmp);
                app.norm_obj.XData=tmp(1,:);
                app.norm_obj.YData=tmp(2,:);
                app.norm_obj.ZData=tmp(3,:);
            end;
            
            if(~isempty(app.up_obj))
                tmp=[app.up_obj.XData; app.up_obj.YData; app.up_obj.ZData; ones(size(app.up_obj.XData))];
                tmp=(R_offset_mm*tmp);
                app.up_obj.XData=tmp(1,:);
                app.up_obj.YData=tmp(2,:);
                app.up_obj.ZData=tmp(3,:);
            end;


            %transform data
            tmp=(R_offset_mm*[etc_render_fsbrain.object.UserData.Origin(:)' 1]')';
            etc_render_fsbrain.object.UserData.Origin=tmp(1:3);

            tmp=(eye(4)*[etc_render_fsbrain.object.UserData.Axis(:)' 1]')';
            etc_render_fsbrain.object.UserData.Axis=tmp(1:3);

            tmp=(eye(4)*[etc_render_fsbrain.object.UserData.Up(:)' 1]')';
            etc_render_fsbrain.object.UserData.Up=tmp(1:3);


        end

        % Value changed function: horizSlider
        function horizSliderValueChanged(app, event)
            value = app.horizSlider.Value;
                       
            global etc_render_fsbrain;


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %define the target in the brain
            click_idx= str2num(app.vertexindexEditField.Value); %<------define target indexx
            target=etc_render_fsbrain.vertex_coords(click_idx,:);


            R_c=eye(4);
            R_c_mm=eye(4);
            R_c(1:3,4)=-target(1:3)./1e3;
            R_c_mm(1:3,4)=-target(1:3);

            %coil_axis=etc_render_fsbrain.object.UserData.Axis(:).';
            coil_axis=etc_render_fsbrain.object.UserData.Up(:);
            tmp=coil_axis./norm(coil_axis);
            x=tmp(1);
            y=tmp(2);
            z=tmp(3);

            
            if(isempty(app.coil_center_no_tune)) return; end; %taget not first defined by default

            v1=app.coil_center_no_tune(:)-target(:);
            v2=etc_render_fsbrain.object.UserData.Origin(:)-target(:);

            deg = atan2(norm(cross(v2,v1)), dot(v2,v1));
            if(dot(coil_axis,cross(v1,v2))>0) deg=-deg; end;
            %fprintf('coil now is %1.0f (deg) away from untuned orientation.\n',deg*180/pi);

            %determine the rotation between two vectors;
            theta=value./180*pi-deg;
            %theta=value.*pi./180-deg;
            %fprintf('additional %1.0f (deg) rotation from untuned orientation is requested.\n',theta*180/pi);

            r_rotz=[cos(theta)+x*x*(1-cos(theta)), x*y*(1-cos(theta))-z*sin(theta), x*z*(1-cos(theta))+y*sin(theta);
                y*x*(1-cos(theta))+z*sin(theta) cos(theta)+y*y*(1-cos(theta)) y*z*(1-cos(theta))-x*sin(theta);
                z*x*(1-cos(theta))-y*sin(theta) z*y*(1-cos(theta))+x*sin(theta) cos(theta)+z*z*(1-cos(theta))];
            %https://en.wikipedia.org/wiki/Rotation_matrix
            R_rotz=eye(4);
            R_rotz(1:3,1:3)=r_rotz;


            etc_render_fsbrain.object_xfm=inv(R_c)*inv(R_rotz)*(R_c)*etc_render_fsbrain.object_xfm;

            %transform object in visualiation

            vv=etc_render_fsbrain.object.Vertices;
            vv(:,4)=1;
            vv=(inv(R_c_mm)*inv(R_rotz)*(R_c_mm)*vv')';
            etc_render_fsbrain.object.Vertices=vv(:,1:3);


            if(~isempty(app.norm_obj))
                tmp=[app.norm_obj.XData; app.norm_obj.YData; app.norm_obj.ZData; ones(size(app.norm_obj.XData))];
                tmp=((inv(R_c_mm)*inv(R_rotz)*R_c_mm)*tmp);
                %tmp=((inv(R_c)*inv(R_rotz)*R_c)*R_trans_mm*R_tx_now*inv(R_rot)*inv(R_tx_now)*tmp);
                app.norm_obj.XData=tmp(1,:);
                app.norm_obj.YData=tmp(2,:);
                app.norm_obj.ZData=tmp(3,:);
            end;
            
            if(~isempty(app.up_obj))
                tmp=[app.up_obj.XData; app.up_obj.YData; app.up_obj.ZData; ones(size(app.up_obj.XData))];
                tmp=((inv(R_c_mm)*inv(R_rotz)*R_c_mm)*tmp);
                %tmp=((inv(R_c)*inv(R_rotz)*R_c)*R_trans_mm*R_tx_now*inv(R_rot)*inv(R_tx_now)*tmp);
                app.up_obj.XData=tmp(1,:);
                app.up_obj.YData=tmp(2,:);
                app.up_obj.ZData=tmp(3,:);
            end;


            %transform data
            tmp=((inv(R_c_mm)*inv(R_rotz)*R_c_mm)*[etc_render_fsbrain.object.UserData.Origin(:)' 1]')';
            etc_render_fsbrain.object.UserData.Origin=tmp(1:3);

            tmp=(inv(R_rotz)*[etc_render_fsbrain.object.UserData.Axis(:)' 1]')';
            etc_render_fsbrain.object.UserData.Axis=tmp(1:3);

            tmp=(inv(R_rotz)*[etc_render_fsbrain.object.UserData.Up(:)' 1]')';
            etc_render_fsbrain.object.UserData.Up=tmp(1:3);


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            v1=app.coil_center_no_tune(:)-target(:);
            v2=etc_render_fsbrain.object.UserData.Origin(:)-target(:);
            deg = atan2(norm(cross(v2,v1)), dot(v2,v1));
            if(dot(coil_axis,cross(v1,v2))>0) deg=-deg; end;
            %fprintf('Finally, coil now is %1.0f (deg) away from untuned orientation.\n',deg*180/pi);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TMSNavFigure = uifigure('Visible', 'off');
            app.TMSNavFigure.Name = 'MATLAB App';
            app.TMSNavFigure.CloseRequestFcn = createCallbackFcn(app, @TMSNavFigureCloseRequest, true);
            app.ScalpPanel = uipanel(app.TMSNavFigure);
            app.ScalpPanel.Title = 'Scalp';
            app.ShowscalpCheckBox = uicheckbox(app.ScalpPanel);
            app.ShowscalpCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowscalpCheckBoxValueChanged, true);
            app.ShowscalpCheckBox.Text = 'Show scalp';
            app.surfaceDropDownLabel = uilabel(app.ScalpPanel);
            app.surfaceDropDownLabel.Text = 'surface';
            app.SurfaceDropDown = uidropdown(app.ScalpPanel);
            app.SButton = uibutton(app.ScalpPanel, 'push');
            app.SButton.ButtonPushedFcn = createCallbackFcn(app, @SButtonPushed, true);
            app.SButton.Text = 'S';
            app.TMStargetPanel = uipanel(app.TMSNavFigure);
            app.TMStargetPanel.Title = 'TMS target';
            app.GoButton = uibutton(app.TMStargetPanel, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.coilmodelDropDownLabel = uilabel(app.TMStargetPanel);
            app.coilmodelDropDownLabel.Text = 'coil model';
            app.coilmodelDropDown = uidropdown(app.TMStargetPanel);
            app.coilmodelDropDown.Items = {};
            app.coilmodelDropDown.Value = {};
            app.MNIEditField_2Label = uilabel(app.TMStargetPanel);
            app.MNIEditField_2Label.Text = 'MNI';
            app.MNIEditField = uieditfield(app.TMStargetPanel, 'text');
            app.MNIEditField.ValueChangedFcn = createCallbackFcn(app, @MNIEditFieldValueChanged, true);
            app.CRSEditFieldLabel = uilabel(app.TMStargetPanel);
            app.CRSEditFieldLabel.Text = 'CRS';
            app.CRSEditField = uieditfield(app.TMStargetPanel, 'text');
            app.CRSEditField.ValueChangedFcn = createCallbackFcn(app, @CRSEditFieldValueChanged, true);
            app.XYZEditFieldLabel = uilabel(app.TMStargetPanel);
            app.XYZEditFieldLabel.Text = 'XYZ';
            app.XYZEditField = uieditfield(app.TMStargetPanel, 'text');
            app.XYZEditField.ValueChangedFcn = createCallbackFcn(app, @XYZEditFieldValueChanged, true);
            app.vertexindexEditFieldLabel = uilabel(app.TMStargetPanel);
            app.vertexindexEditFieldLabel.Text = 'vertex index';
            app.vertexindexEditField = uieditfield(app.TMStargetPanel, 'text');
            app.vertexindexEditField.ValueChangedFcn = createCallbackFcn(app, @vertexindexEditFieldValueChanged, true);
            app.LoadcoilButton = uibutton(app.TMStargetPanel, 'push');
            app.LoadcoilButton.ButtonPushedFcn = createCallbackFcn(app, @LoadcoilButtonPushed, true);
            app.LoadcoilButton.Text = 'Load coil';
            app.xButton = uibutton(app.TMStargetPanel, 'push');
            app.xButton.ButtonPushedFcn = createCallbackFcn(app, @xButtonPushed, true);
            app.xButton.Text = 'x';
            app.LoadstrcoilButton = uibutton(app.TMStargetPanel, 'push');
            app.LoadstrcoilButton.ButtonPushedFcn = createCallbackFcn(app, @LoadstrcoilButtonPushed, true);
            app.LoadstrcoilButton.Text = 'Load strcoil';
            app.CoilLamp = uilamp(app.TMStargetPanel);
            app.StrcoilLamp = uilamp(app.TMStargetPanel);
            app.VecNormCheckBox = uicheckbox(app.TMStargetPanel);
            app.VecNormCheckBox.ValueChangedFcn = createCallbackFcn(app, @VecNormCheckBoxValueChanged, true);
            app.VecNormCheckBox.Text = 'norm.';
            app.VecUpCheckBox = uicheckbox(app.TMStargetPanel);
            app.VecUpCheckBox.ValueChangedFcn = createCallbackFcn(app, @VecUpCheckBoxValueChanged, true);
            app.VecUpCheckBox.Text = 'up';
            app.TunePanel = uipanel(app.TMSNavFigure);
            app.TunePanel.Title = 'Tune';
            app.vertSliderLabel = uilabel(app.TunePanel);
            app.vertSliderLabel.Text = 'vert.';
            app.vertSlider = uislider(app.TunePanel);
            app.vertSlider.Limits = [-30 30];
            app.vertSlider.ValueChangedFcn = createCallbackFcn(app, @vertSliderValueChanged, true);
            app.rotateSliderLabel = uilabel(app.TunePanel);
            app.rotateSliderLabel.Text = 'rotate';
            app.rotateSlider = uislider(app.TunePanel);
            app.rotateSlider.Limits = [-180 180];
            app.rotateSlider.ValueChangedFcn = createCallbackFcn(app, @rotateSliderValueChanged, true);
            app.horizSliderLabel = uilabel(app.TunePanel);
            app.horizSliderLabel.Text = 'horiz.';
            app.horizSlider = uislider(app.TunePanel);
            app.horizSlider.Limits = [-30 30];
            app.horizSlider.ValueChangedFcn = createCallbackFcn(app, @horizSliderValueChanged, true);
            app.offsetSliderLabel = uilabel(app.TunePanel);
            app.offsetSliderLabel.Text = 'offset';
            app.offsetSlider = uislider(app.TunePanel);
            app.offsetSlider.Limits = [0 20];
            app.offsetSlider.ValueChangedFcn = createCallbackFcn(app, @offsetSliderValueChanged, true);
            app.ExportButton = uibutton(app.TMSNavFigure, 'push');
            app.ExportButton.Text = 'Export';
            app.ImportButton = uibutton(app.TMSNavFigure, 'push');
            app.ImportButton.Text = 'Import';
            app.TextArea = uitextarea(app.TMSNavFigure);
            app.EfieldPanel = uipanel(app.TMSNavFigure);
            app.EfieldPanel.Title = 'E-field';
            app.CalcButton = uibutton(app.EfieldPanel, 'push');
            app.CalcButton.Text = 'Calc.';
            % Show the figure after all components are created
            app.TMSNavFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = etc_render_fsbrain_tms_nav

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TMSNavFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TMSNavFigure)
        end
    end
end